classdef appsincos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        SinSwitchLabel            matlab.ui.control.Label
        SinSwitch                 matlab.ui.control.ToggleSwitch
        CosSwitchLabel            matlab.ui.control.Label
        CosSwitch                 matlab.ui.control.ToggleSwitch
        withdotsCheckBox          matlab.ui.control.CheckBox
        SelectcolorDropDownLabel  matlab.ui.control.Label
        SelectcolorDropDown       matlab.ui.control.DropDown
        WidthEditFieldLabel       matlab.ui.control.Label
        WidthEditField            matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Value changed function: SinSwitch
        function sin(app, event)
            value = app.SinSwitch.Value;
            if strcmp(value, 'On')
                    plot(app.UIAxes,0:pi/12:pi,sin(0:pi/12:pi))
            else
                plot(app.UIAxes,0:pi/12:pi,sin(0:pi/12:pi), 'Color', [1,1,1])
            end
        end

        % Value changed function: CosSwitch
        function cos(app, event)
            value = app.CosSwitch.Value;
            hold(app.UIAxes, 'on')
            width = app.WidthEditField.Value;
            if width == 0
                width = 1;
            end
            color = app.SelectcolorDropDown.Value;
            if strcmp(color, 'Red')
                col = 'r';
            end
            if strcmp(color, 'Green')
                    col = 'g';
            end
            if strcmp(color, 'Blue')
                col = 'b';
            end
            if strcmp(value, 'On')
                if app.withdotsCheckBox.Value == 1
                    plot(app.UIAxes,0:pi/12:pi,cos(0:pi/12:pi), '.', 'LineWidth', width, 'Color', col)
                else 
                    plot(app.UIAxes,0:pi/12:pi,cos(0:pi/12:pi), 'LineWidth', width, 'Color', col)
                end
            else
                plot(app.UIAxes,0:pi/12:pi,cos(0:pi/12:pi), 'Color', [1,1,1], 'LineWidth', width)
                plot(app.UIAxes,0:pi/12:pi,cos(0:pi/12:pi), '.', 'Color', [1,1,1], 'LineWidth', width)
            end
        end

        % Callback function
        function style(app, event)
            value = app.withdotsCheckBox.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SinSwitchLabel = uilabel(app.UIFigure);
            app.SinSwitchLabel.Text = 'Sin';
            app.SinSwitch = uiswitch(app.UIFigure, 'toggle');
            app.SinSwitch.ValueChangedFcn = createCallbackFcn(app, @sin, true);
            app.CosSwitchLabel = uilabel(app.UIFigure);
            app.CosSwitchLabel.Text = 'Cos';
            app.CosSwitch = uiswitch(app.UIFigure, 'toggle');
            app.CosSwitch.ValueChangedFcn = createCallbackFcn(app, @cos, true);
            app.withdotsCheckBox = uicheckbox(app.UIFigure);
            app.withdotsCheckBox.Text = 'with dots';
            app.SelectcolorDropDownLabel = uilabel(app.UIFigure);
            app.SelectcolorDropDownLabel.Text = 'Select color';
            app.SelectcolorDropDown = uidropdown(app.UIFigure);
            app.SelectcolorDropDown.Items = {'Red', 'Green', 'Blue'};
            app.SelectcolorDropDown.Value = 'Red';
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = appsincos

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end